classdef DentateSpikeInterface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        PreprocessLFPTab               matlab.ui.container.Tab
        PlotsettingsPanel              matlab.ui.container.Panel
        PlotButton                     matlab.ui.control.Button
        ShiftuVEditField               matlab.ui.control.NumericEditField
        ShiftuVEditFieldLabel          matlab.ui.control.Label
        t1sEditField                   matlab.ui.control.NumericEditField
        t1sEditFieldLabel              matlab.ui.control.Label
        t0sEditField                   matlab.ui.control.NumericEditField
        t0sEditFieldLabel              matlab.ui.control.Label
        LFPtoplotButtonGroup           matlab.ui.container.ButtonGroup
        fixedLFPButton                 matlab.ui.control.RadioButton
        rawLFPButton                   matlab.ui.control.RadioButton
        FixdeadchannelsPanel           matlab.ui.container.Panel
        ChannelSpinner                 matlab.ui.control.Spinner
        ChannelSpinnerLabel            matlab.ui.control.Label
        ShankSpinner                   matlab.ui.control.Spinner
        ShankSpinnerLabel              matlab.ui.control.Label
        Lamp_2                         matlab.ui.control.Lamp
        FixchannelsButton              matlab.ui.control.Button
        LabelselectedButtonGroup       matlab.ui.container.ButtonGroup
        ConnectedButton                matlab.ui.control.RadioButton
        DeadButton                     matlab.ui.control.RadioButton
        DetectButton                   matlab.ui.control.Button
        SimilaritythresholdEditField   matlab.ui.control.NumericEditField
        SimilaritythresholdEditFieldLabel  matlab.ui.control.Label
        ofneighborsSpinner             matlab.ui.control.Spinner
        ofneighborsSpinnerLabel        matlab.ui.control.Label
        LFPViewerPanel                 matlab.ui.container.Panel
        LoadfilterdataPanel            matlab.ui.container.Panel
        SelectdataLabel                matlab.ui.control.Label
        HighcutoffHzEditField          matlab.ui.control.NumericEditField
        HighcutoffHzEditFieldLabel     matlab.ui.control.Label
        LowcutoffHzEditField           matlab.ui.control.NumericEditField
        LowcutoffHzEditFieldLabel      matlab.ui.control.Label
        Lamp                           matlab.ui.control.Lamp
        LoadDataButton                 matlab.ui.control.Button
        ProbeLayoutPanel               matlab.ui.container.Panel
        DetectDSTab                    matlab.ui.container.Tab
        PutativeDSViewerPanel          matlab.ui.container.Panel
        ProbeLayoutPanel_2             matlab.ui.container.Panel
        PlotsettingsPanel_2            matlab.ui.container.Panel
        Switch_3                       matlab.ui.control.Switch
        DSSpinner                      matlab.ui.control.Spinner
        DSSpinnerLabel                 matlab.ui.control.Label
        LFPtoplotButtonGroup_2         matlab.ui.container.ButtonGroup
        fixedLFPButton_2               matlab.ui.control.RadioButton
        rawLFPButton_2                 matlab.ui.control.RadioButton
        ShiftuVEditField_2             matlab.ui.control.NumericEditField
        ShiftuVEditField_2Label        matlab.ui.control.Label
        PlotmeanCheckBox               matlab.ui.control.CheckBox
        TimewindowmsEditField          matlab.ui.control.NumericEditField
        TimewindowmsEditFieldLabel     matlab.ui.control.Label
        PlotButton_2                   matlab.ui.control.Button
        TextArea                       matlab.ui.control.TextArea
        DSdetectionPanel               matlab.ui.container.Panel
        ReferencechEditField           matlab.ui.control.NumericEditField
        ReferencechEditFieldLabel      matlab.ui.control.Label
        MaxampuVEditField              matlab.ui.control.NumericEditField
        MaxampuVEditFieldLabel         matlab.ui.control.Label
        Lamp_4                         matlab.ui.control.Lamp
        DetectDSButton                 matlab.ui.control.Button
        MaxwidthmsEditField            matlab.ui.control.NumericEditField
        MaxwidthmsEditFieldLabel       matlab.ui.control.Label
        MinwidthmsEditField            matlab.ui.control.NumericEditField
        MinwidthmsEditFieldLabel       matlab.ui.control.Label
        ThresholdzscoreEditField       matlab.ui.control.NumericEditField
        ThresholdzscoreEditFieldLabel  matlab.ui.control.Label
        UsechannelEditField            matlab.ui.control.NumericEditField
        UsechannelEditFieldLabel       matlab.ui.control.Label
        UseshankSpinner                matlab.ui.control.Spinner
        UseshankSpinnerLabel           matlab.ui.control.Label
        ChannelselectionButtonGroup    matlab.ui.container.ButtonGroup
        ManualButton                   matlab.ui.control.RadioButton
        AutomaticestimationButton      matlab.ui.control.RadioButton
        EstimateMLTab                  matlab.ui.container.Tab
        UseshankSpinner_2              matlab.ui.control.Spinner
        UseshankSpinner_2Label         matlab.ui.control.Label
        AddlayersPanel                 matlab.ui.container.Panel
        AddlayersButton                matlab.ui.control.Button
        lowerMMLEditField              matlab.ui.control.NumericEditField
        lowerMMLEditFieldLabel         matlab.ui.control.Label
        lowerOMLEditField              matlab.ui.control.NumericEditField
        lowerOMLEditFieldLabel         matlab.ui.control.Label
        ClusteringPanel                matlab.ui.container.Panel
        RunPCAButton                   matlab.ui.control.Button
        ToEditField                    matlab.ui.control.NumericEditField
        ToEditFieldLabel               matlab.ui.control.Label
        FromEditField                  matlab.ui.control.NumericEditField
        FromEditFieldLabel             matlab.ui.control.Label
        PCAchannelsLabel               matlab.ui.control.Label
        PCYSpinner                     matlab.ui.control.Spinner
        PCYSpinnerLabel                matlab.ui.control.Label
        PCXSpinner                     matlab.ui.control.Spinner
        PCXSpinnerLabel                matlab.ui.control.Label
        MethodButtonGroup              matlab.ui.container.ButtonGroup
        ManualButton_2                 matlab.ui.control.RadioButton
        PCXButton                      matlab.ui.control.RadioButton
        ClusterButton                  matlab.ui.control.Button
        ileEditField                   matlab.ui.control.NumericEditField
        ileEditFieldLabel              matlab.ui.control.Label
        HILEditField_2                 matlab.ui.control.NumericEditField
        HILEditField_2Label            matlab.ui.control.Label
        MMLEditField                   matlab.ui.control.NumericEditField
        MMLEditFieldLabel              matlab.ui.control.Label
        OMLEditField                   matlab.ui.control.NumericEditField
        OMLEditFieldLabel              matlab.ui.control.Label
        PlotsettingsPanel_4            matlab.ui.container.Panel
        HeatmapDropDown                matlab.ui.control.DropDown
        HeatmapDropDownLabel           matlab.ui.control.Label
        climEditField                  matlab.ui.control.NumericEditField
        climEditFieldLabel             matlab.ui.control.Label
        PlotButton_3                   matlab.ui.control.Button
        CurrentsourcedensityPanel      matlab.ui.container.Panel
        Lamp_5                         matlab.ui.control.Lamp
        ComputeButton                  matlab.ui.control.Button
        KernelsigmaEditField           matlab.ui.control.NumericEditField
        KernelsigmaEditFieldLabel      matlab.ui.control.Label
        KernellengthEditField          matlab.ui.control.NumericEditField
        KernellengthEditFieldLabel     matlab.ui.control.Label
        TimewindowmsEditField_2        matlab.ui.control.NumericEditField
        TimewindowmsEditField_2Label   matlab.ui.control.Label
        ClusterViewerPanel             matlab.ui.container.Panel
        ProbeLayoutPanel_3             matlab.ui.container.Panel
        ClassifyDSTab_2                matlab.ui.container.Tab
        ReadjustMLPanel                matlab.ui.container.Panel
        EditMLButton                   matlab.ui.control.Button
        HILEditField                   matlab.ui.control.NumericEditField
        HILEditFieldLabel              matlab.ui.control.Label
        MMLEditField_2                 matlab.ui.control.NumericEditField
        MMLEditField_2Label            matlab.ui.control.Label
        OMLEditField_2                 matlab.ui.control.NumericEditField
        OMLEditField_2Label            matlab.ui.control.Label
        SaveresultsPanel               matlab.ui.container.Panel
        FilenameEditField              matlab.ui.control.EditField
        FilenameEditFieldLabel         matlab.ui.control.Label
        SaveButton                     matlab.ui.control.Button
        ContributionViewerPanel        matlab.ui.container.Panel
        PlotsettingsPanel_5            matlab.ui.container.Panel
        ShiftuVEditField_3             matlab.ui.control.NumericEditField
        ShiftuVEditField_3Label        matlab.ui.control.Label
        ShowcoincidenceCheckBox        matlab.ui.control.CheckBox
        TimewindowmsEditField_3        matlab.ui.control.NumericEditField
        TimewindowmsEditField_3Label   matlab.ui.control.Label
        MultichannelCheckBox           matlab.ui.control.CheckBox
        HeatmapDropDown_2              matlab.ui.control.DropDown
        HeatmapDropDown_2Label         matlab.ui.control.Label
        climEditField_2                matlab.ui.control.NumericEditField
        climEditField_2Label           matlab.ui.control.Label
        DStoplotButtonGroup            matlab.ui.container.ButtonGroup
        DS3Button                      matlab.ui.control.RadioButton
        DS2Button                      matlab.ui.control.RadioButton
        DS1Button                      matlab.ui.control.RadioButton
        MultishankCheckBox             matlab.ui.control.CheckBox
        DSSpinner_2                    matlab.ui.control.Spinner
        DSSpinner_2Label               matlab.ui.control.Label
        PlotmeanCheckBox_2             matlab.ui.control.CheckBox
        PlotButton_4                   matlab.ui.control.Button
        Switch_2                       matlab.ui.control.Switch
        DSViewerPanel                  matlab.ui.container.Panel
        ClassificationPanel            matlab.ui.container.Panel
        SinkcutoffEditField            matlab.ui.control.NumericEditField
        SinkcutoffEditFieldLabel       matlab.ui.control.Label
        UseshankSpinner_3              matlab.ui.control.Spinner
        UseshankSpinner_3Label         matlab.ui.control.Label
        ClassifyButton                 matlab.ui.control.Button
    end


    properties (Access = private)

    end

    properties (Access = public)
        red_color = [0.8 0.2 0.2]; % color for lamp
        green_color = [0.3 0.5 0.3]; % color for lamp
        selection_color= [224 194 13]./255; % color used for selected channel
        gcl_color = [140 191 88]./255 % color for granule cell layer
        DG_colors = ["#418E9F","#CB7DF0","#8686C8" "#386cb5 "  "#AE00FF" "#418E9F"]; % colors used for different DG channels and DSs : OML/DSL, MML/DSM, DSLM, GCL; lower MML; lower OML
        DS_type= ["DS_1", "DS_2", "DS_3"]; % string to store DS type names
        workdir; % path where the data lives
        config; % struct storing probe parameters (loaded from existing mat file)
        sampleRate; % sample rate (downsampled or original) (Hz)
        LFPmat; % matrix to store LFP - ordered based on channel map (from tip to top)
        N_shank; % number of shanks
        N_channel; % number of channels
        recordingDur; % duration of recording in seconds
        LFPTiledLayout; % TL for plotting lfp on multiple shanks
        preparams; % struct to store settings of preprocessing
        SimilarityMatrix; % matrix to store similarity matrix (detect dead channels)
        LFPmatFixed=[]; % matrix to store lfp in which dead channels are interpolated
        LFPToPlot; % string which lfp to plot
        LFPmatFiltered; % matrix to store bandpass filtered lfp
        SelectedChannel= [-1 -1]; % to store which channel is selected [shank channel]
        UseShank = -1; % to store which shank is used for analyis
        det_params; % struct to store DS detection parameters
        DS_struct; % struct to store results from DS detection
        DG_layers; % struct to store GCL, MML, OML
        TabOpen = 0; % variable to decide whether the current step is DS detection
        classifier_params ; % struct to store paramaters for DS classification
        CSD; % struct to store csd
        DS_LFP; % struct to store DS LFP corresponding to the CSD window
        PCA_res; % struct to store the pca results
        PCX; % variable for selected PC on X-axis
        PCY; % variable for selected PC on Y-axis
        EstimatedML; % struct for estimated OML/MML & mean DS1 and DS2 waveform
        ClusterTiledLayout; % tl for plotting pca clusters
        ClusterAx; % axis for manual clustering
        meanCSDAx; % axis for selecting lower blade mol layer chs
        NClustEst; % variable for number of clusters for OML/MML estimation
        MeanWaveForm;% storing mean waveform for different DS types
        Classify_res_raw; % struct to store classification results
        Classify_res_final; % struct to store results when DS3s are separated based on OML/MML contribution
        DS_idxToPlot; % for individual DS plotting
        MeanLFP_DS = []; % mean lfp of DSs stored in this matrix (for each channel)
    end

    methods (Access = public)

        function PlotLFP(app)

            % decide which panel to use
            switch app.TabOpen
                case 0
                    SelectedPanel = app.LFPViewerPanel;
                    selected_lfp = app.LFPtoplotButtonGroup.SelectedObject;
                    shift = app.ShiftuVEditField.Value;

                case 1
                    SelectedPanel = app.PutativeDSViewerPanel;
                    selected_lfp = app.LFPtoplotButtonGroup_2.SelectedObject;
                    shift = app.ShiftuVEditField_2.Value;
            end


            % decide which lfp to plot
            switch selected_lfp.Text
                case "raw LFP"
                    lfp = app.LFPmat;

                case "fixed LFP"
                    lfp = app.LFPmatFixed;

            end

            app.LFPTiledLayout = tiledlayout(SelectedPanel, 1,app.N_shank);

            switch app.TabOpen
                case 0
                    t0 = round(app.t0sEditField.Value*app.sampleRate);
                    t1 = round(app.t1sEditField.Value*app.sampleRate);
                case 1
                    n=  app.DSSpinner.Value;
                    win = app.TimewindowmsEditField.Value*app.sampleRate/1000;
                    t0 = app.DS_struct{app.UseShank}.time(n)-win;
                    t1 = app.DS_struct{app.UseShank}.time(n)+win;
            end

            if t0 < 1
                t0 =1;
            end
            if t1 > length(lfp(:,1))
                t1 = length(lfp(:,1));
            end

            for shank = 1:app.N_shank

                ax = nexttile(app.LFPTiledLayout);
                hold(ax, "on")
                for i = 1:app.N_channel % 1st channel is the tip

                    plot(ax,(t0:t1)./app.sampleRate, lfp(t0:t1, i, shank)+i*shift, 'k-')

                    if app.config.DeadChannel(i,shank) == 1
                        plot(ax,(t0:t1)./app.sampleRate, lfp(t0:t1, i, shank)+i*shift, '-', 'Color', app.red_color)
                    end

                    if app.SelectedChannel(1) == shank && app.SelectedChannel(2) == i
                        plot(ax,(t0:t1)./app.sampleRate, lfp(t0:t1,i, shank)+i*shift, '-', 'Color', app.selection_color)
                    end

                    for l = 1:4
                        if ~isempty(app.DS_struct{shank}) && i == app.DG_layers{shank}(l)
                            plot(ax,(t0:t1)./app.sampleRate, lfp(t0:t1, i, shank)+i*shift, '-', 'Color', app.DG_colors(l))
                        end
                    end


                end
                if app.TabOpen == 1
                    xline(ax,((t1-t0)/2+t0)./app.sampleRate,'--')
                end
                xlabel(ax,"Time (s)")
                title(ax,strcat("Shank ", num2str(shank)))
                yticks(ax,[])
            end


        end

        function PlotProbe(app)

            switch app.TabOpen
                case 0
                    SelectedPanel = app.ProbeLayoutPanel;
                    app.UseShank = 1;

                case 1
                    SelectedPanel = app.ProbeLayoutPanel_2;
                    app.UseShank = app.UseshankSpinner.Value;

                case 2
                    SelectedPanel = app.ProbeLayoutPanel_3;
                    app.UseShank = app.UseshankSpinner_2.Value;

            end

            t = tiledlayout(SelectedPanel, "flow");
            ax = nexttile(t);
            hold(ax, "on")

            for shank = 1:app.N_shank
                for i = 1:app.N_channel

                    plot(ax, app.config.x_coords(i,shank), app.config.y_coords(i,shank), 'Marker','square', "MarkerSize",7, "Color",  "k", "MarkerFaceColor", "k")


                    if app.config.DeadChannel(i,shank) == 1
                        plot(ax, app.config.x_coords(i,shank), app.config.y_coords(i,shank), 'Marker','square', "MarkerSize",7, "Color", app.red_color, "MarkerFaceColor", app.red_color)
                    end

                    for l = 1:6
                        if ~isempty(app.DS_struct{shank}) && i == app.DG_layers{shank}(l)
                            plot(ax, app.config.x_coords(i,shank), app.config.y_coords(i,shank), 'Marker','square', "MarkerSize",7, "Color", app.DG_colors(l), "MarkerFaceColor", app.DG_colors(l));
                        end
                    end


                    if app.SelectedChannel(1) == shank && app.SelectedChannel(2) == i
                        plot(ax, app.config.x_coords(i,shank), app.config.y_coords(i,shank), 'Marker','square', "MarkerSize",7, "Color", app.selection_color, "MarkerFaceColor", app.selection_color)
                    end


                end
            end
            xlabel(ax,"X (\mum)")
            xlim(ax,[min(app.config.x_coords,[], "all")-50 max(app.config.x_coords,[], "all")+50])
            xticks(ax, unique(app.config.x_coords))
            ylabel(ax,"Y (\mum)")
            ylim(ax,[min(app.config.y_coords,[], "all")-50 max(app.config.y_coords,[], "all")+50])
            fontsize(ax,6,"points")

        end


        function PlotMeanLFP(app)

            % pick lfp

            % calculate mean LFP for each channel
            switch app.TabOpen
                case 0
                    disp("No mean data to display")
                case 1
                    shift = app.ShiftuVEditField_2.Value;
                    SelectedPanel = app.PutativeDSViewerPanel;
                    selected_lfp = app.LFPtoplotButtonGroup_2.SelectedObject;

                    switch selected_lfp.Text
                        case "raw LFP"
                            lfp = app.LFPmat;

                        case "fixed LFP"
                            lfp = app.LFPmatFixed;

                        case "filtered LFP"
                            lfp = app.LFPmatFiltered;
                    end

                    
                        win = app.TimewindowmsEditField.Value*app.sampleRate/1000;
                        N_ds = length(app.DS_struct{app.UseShank}.time);
                      if isempty(app.MeanLFP_DS{app.UseShank}) | 2*win+1 ~= length(app.MeanLFP_DS{app.UseShank}(:,1,1))
                        if N_ds > 500
                            N_ds = 500;
                            disp("Taking mean on first 500 events only...")
                        end
                        LFP_section = zeros(2*win+1,app.N_channel, app.N_shank, N_ds);
    
                        for i = 1:N_ds
                            start = app.DS_struct{app.UseShank}.time(i)-win;
                            stop = app.DS_struct{app.UseShank}.time(i)+win;
                            if start < 1
                                start = 1;
                            end
                            if stop > length(lfp(:,1,1))
                                stop = length(lfp(:,1,1));
                            end
    
                            LFP_section(1:stop-start+1,:,:,i) =  lfp(start:stop, :, :);
                        end
                        app.MeanLFP_DS{app.UseShank} = mean(LFP_section,4);
                    end
                    app.LFPTiledLayout = tiledlayout(SelectedPanel, 1,app.N_shank);
                    for shank = 1:app.N_shank

                        ax = nexttile(app.LFPTiledLayout);
                        hold(ax, "on")
                        for i = 1:app.N_channel % 1st channel is the tip

                            plot(ax,(-win:win)./app.sampleRate.*1000, app.MeanLFP_DS{app.UseShank}(:, i, shank)+i*shift, 'k-')

                            if app.config.DeadChannel(i,shank) == 1
                                plot(ax,(-win:win)./app.sampleRate.*1000, app.MeanLFP_DS{app.UseShank}(:, i, shank)+i*shift, '-', 'Color', app.red_color)
                            end

                            for l = 1:4
                                if ~isempty(app.DS_struct{shank}) && i == app.DG_layers{shank}(l)
                                    plot(ax,(-win:win)./app.sampleRate.*1000, app.MeanLFP_DS{app.UseShank}(:, i, shank)+i*shift, '-', 'Color', app.DG_colors(l))
                                end
                            end

                            if app.SelectedChannel(1) == shank && app.SelectedChannel(2) == i
                                plot(ax,(-win:win)./app.sampleRate.*1000, app.MeanLFP_DS{app.UseShank}(:, i, shank)+i*shift, '-', 'Color', app.selection_color)
                            end

                        end
                        yticks(ax,[])
                        xlabel(ax,"Time (ms)")
                        title(ax,strcat("Shank ", num2str(shank)))
                        fontsize(ax,10,"points")
                    end


            end
        end


        function PlotPCAClusters(app)

            app.PCX = app.PCXSpinner.Value;
            app.PCY = app.PCYSpinner.Value;

            time = (-app.TimewindowmsEditField_2.Value:1/(app.sampleRate/1000):app.TimewindowmsEditField_2.Value);
            depth = app.config.y_coords(:,app.UseShank);
            app.ClusterTiledLayout= tiledlayout(app.ClusterViewerPanel,2,2);

            %% figure 1 - plot DSs in PC1-PC2 space
            app.ClusterAx = nexttile(app.ClusterTiledLayout);
            hold(app.ClusterAx, "on")
            plot(app.ClusterAx, app.PCA_res{app.UseShank}.coords(:,app.PCX), app.PCA_res{app.UseShank}.coords(:,app.PCY), 'o', 'MarkerSize', 4, "Color",[.5 .5 .5], "MarkerFaceColor", [.5 .5 .5])
            for i = 1:app.NClustEst{app.UseShank}
                if ismember(i,app.DS_struct{app.UseShank}.clust_idx) == 1
                    plot(app.ClusterAx, app.PCA_res{app.UseShank}.coords(app.DS_struct{app.UseShank}.clust_idx == i,app.PCX), app.PCA_res{app.UseShank}.coords(app.DS_struct{app.UseShank}.clust_idx == i,app.PCY), 'o', 'MarkerSize', 4, "Color",app.DG_colors(i), "MarkerFaceColor", app.DG_colors(i))
                end
            end
            hold(app.ClusterAx, "on")
            xline(app.ClusterAx,0, '--')
            yline(app.ClusterAx,0,"--")
            xlabel(app.ClusterAx, strcat("PC" , num2str(app.PCX)," (exp.:", num2str(round(app.PCA_res{app.UseShank}.explained(app.PCX))), "%)"))
            ylabel(app.ClusterAx, strcat("PC" , num2str(app.PCY)," (exp.:", num2str(round(app.PCA_res{app.UseShank}.explained(app.PCY))), "%)"))
            xlim(app.ClusterAx,[min(app.PCA_res{app.UseShank}.coords(:,app.PCX))-0.1  max(app.PCA_res{app.UseShank}.coords(:,app.PCX))+0.1 ])
            ylim(app.ClusterAx,[min(app.PCA_res{app.UseShank}.coords(:,app.PCY))-0.1  max(app.PCA_res{app.UseShank}.coords(:,app.PCY))+0.1 ])
            fontsize(app.ClusterAx,10,"points")

            %% figure 2 - plot mean CSD @ DS peak

            app.meanCSDAx = nexttile(app.ClusterTiledLayout);
            hold(app.meanCSDAx , "on")
            for i = 1:app.NClustEst{app.UseShank}
                plot(app.meanCSDAx ,app.EstimatedML{app.UseShank}.mean_CSD{i}(:,round(length(time)/2)), depth, 'o-', 'Color', app.DG_colors(i))

            end
            xline(app.meanCSDAx ,0, '--')
            ylabel(app.meanCSDAx,'Depth (µm)')
            yline(app.meanCSDAx, depth(app.OMLEditField.Value), "--", "Color", app.DG_colors(1))
            yline(app.meanCSDAx, depth(app.MMLEditField.Value), "--", "Color", app.DG_colors(2))
            yline(app.meanCSDAx, depth(app.HILEditField_2.Value), "--", "Color", app.DG_colors(4))
            fontsize(app.meanCSDAx,10,"points")

            %% figure 3-4 if switch is on csd - plot mean csd in full time window
            
                for i = 1:app.NClustEst{app.UseShank}
                    ax = nexttile(app.ClusterTiledLayout);
                    PlotCSDmat(app, ax, time,depth, app.EstimatedML{app.UseShank}.mean_CSD{i}, app.climEditField.Value)
                    title(ax, strcat("CSD of clust ", num2str(i)))
                    fontsize(ax,10,"points")
                end

        end

        function PlotCSDmat(app, ax, time,depth, csd, colorlim)

            surf(ax,time,depth, csd)
            view(ax,[0,90])
            ylim(ax,[depth(1) depth(end)])
            shading(ax,"flat")
            xlabel(ax,'Time (ms)')
            ylabel(ax,'Depth (µm)')
            clim(ax,[-colorlim, colorlim])
            colormap(ax, app.HeatmapDropDown.Value)

        end

        function MeanWaveForm = GetMeanWaveForm(app, idx)
            N_clust = length(unique(idx));
            switch app.TabOpen
                case 2
                    selected_lfp = app.LFPtoplotButtonGroup_2.SelectedObject;
                case 3
                    selected_lfp = app.LFPtoplotButtonGroup_2.SelectedObject;
            end
            switch selected_lfp.Text
                case "raw LFP"
                    lfp = app.LFPmat;

                case "fixed LFP"
                    lfp = app.LFPmatFixed;

            end

            win = app.TimewindowmsEditField_2.Value*app.sampleRate/1000;
            MeanWaveForm = zeros(2*win+1, app.N_channel, app.N_shank, N_clust);
            for c = 1:N_clust
                ds_list = find(idx == c);
                N_ds = length(ds_list);
                LFP_section = zeros(2*win+1,app.N_channel, app.N_shank, N_ds);

                if N_ds > 500 % use only a thousand events when data is too big
                    disp("Using first 500 only...")
                    N_ds = 500;
                end

                for i = 1:N_ds
                    if app.DS_struct{app.UseShank}.time(ds_list(i))-win < 1 | app.DS_struct{app.UseShank}.time(ds_list(i))+win > length(lfp(:,1,1))
                        LFP_section(:,:,:,i) = nan(2*win+1,app.N_channel, app.N_shank);
                    else
                        LFP_section(:,:,:,i) =  lfp(app.DS_struct{app.UseShank}.time(ds_list(i))-win:app.DS_struct{app.UseShank}.time(ds_list(i))+win, :, :);
                    end

                end
                MeanWaveForm(:,:,:,c) = mean(LFP_section,4, 'omitnan');
            end
        end

        function PlotClassification(app)

      
            selectedButton = app.DStoplotButtonGroup.SelectedObject.Text;

            switch selectedButton
                case "DS1"
                    idx = 1;
                    

                case "DS2"
                    idx = 2;

                case "DS3"
                    idx =3;
            end
            showCoincidence = app.ShowcoincidenceCheckBox.Value;
                
            app.DS_idxToPlot = find(app.Classify_res_final{app.UseShank}.DStype == idx);
            if ~isempty(app.DS_idxToPlot)
                    app.DSSpinner_2.Limits = [1 length(app.DS_idxToPlot)];
                    shift = app.ShiftuVEditField_2.Value;
            end
        [N_points,N_channels,~] = size(app.LFPmatFixed);

            %% set up layout

                    if app.MultishankCheckBox.Value == 1
                        shanks = app.N_shank;
                        shank_vec = 1:app.N_shank;
                        shank_vec([1 find(shank_vec == app.UseShank)]) = shank_vec([find(shank_vec == app.UseShank) 1]);
                    else
                        shanks = 1;
                        shank_vec = app.UseShank;
                    end
                    Layout = tiledlayout(app.DSViewerPanel, 2+(shanks-1), 3);
                    DStype = app.Classify_res_final{app.UseShank}.DStype;
                    Ismean = app.PlotmeanCheckBox_2.Value;
                    currentDS = app.DSSpinner_2.Value;
                    IsmultiCh = app.MultichannelCheckBox.Value;
                    colorlim = app.climEditField_2.Value;
                    win= app.TimewindowmsEditField_3.Value;
            

            %% select which DS type to show
            [~,dpoints,~,~] = size(app.CSD{app.UseShank});
            PeakTime = round(dpoints/2);
            depth = app.config.y_coords(:,app.UseShank);
            time = (-app.TimewindowmsEditField_2.Value:1/(app.sampleRate/1000):app.TimewindowmsEditField_2.Value);


            ax_CSD_space = nexttile(Layout,1);
            ax_peak_CSD = nexttile(Layout,2);
            ax_rate = nexttile(Layout,3);
            ax_DS = cell(app.N_shank,3);

            for i = 1:shanks
                for j= 1:3
                    ax_DS{i,j} = nexttile(Layout,3*i+j);
                end
            end

            %% plot OML-MML space
            OML_csd = squeeze(app.CSD{app.UseShank}(app.DG_layers{app.UseShank}(1), PeakTime,:,app.UseShank));
            MML_csd = squeeze(app.CSD{app.UseShank}(app.DG_layers{app.UseShank}(2), PeakTime,:,app.UseShank));
            plot(ax_CSD_space,OML_csd , MML_csd, 'o', 'MarkerSize', 4, 'Color', [.5 .5 .5], "MarkerFaceColor", [.5 .5 .5])
            hold(ax_CSD_space, "on")

            for i = 1:3
                plot(ax_CSD_space, OML_csd(DStype == i) , MML_csd(DStype == i),'o', 'MarkerSize', 4,  'Color', app.DG_colors(i), "MarkerFaceColor", app.DG_colors(i))
            end
            plot(ax_CSD_space, OML_csd(DStype == 1) , MML_csd(DStype == 1),'o', 'MarkerSize', 4,  'Color', app.DG_colors(1), "MarkerFaceColor", app.DG_colors(1))

            if Ismean == 0
              plot(ax_CSD_space, OML_csd(app.DS_idxToPlot(currentDS)) , MML_csd(app.DS_idxToPlot(currentDS)),'o', 'MarkerSize', 4,  'Color', app.selection_color, "MarkerFaceColor", app.selection_color)      
            end

            xline(ax_CSD_space,0, '--')
            yline(ax_CSD_space,0, '--')
            xlabel(ax_CSD_space, "OML", "FontSize", 10)
            ylabel(ax_CSD_space, "MML", "FontSize", 10)
            box(ax_CSD_space, "off")
            fontsize(ax_CSD_space,10,"points")

            %% plot mean csd for each type @ peak or temporal csd @ MML & OML
                hold(ax_peak_CSD, "on")                
                for i = 1:3
                    plot(ax_peak_CSD, mean(app.CSD{app.UseShank}(:,PeakTime,DStype == i, app.UseShank),3), depth, "o-", "Color", app.DG_colors(i))
    
                end
                yline(ax_peak_CSD,depth(app.DG_layers{app.UseShank}(1)), '--',"Color", app.DG_colors(1))
                yline(ax_peak_CSD,depth(app.DG_layers{app.UseShank}(2)), '--',"Color", app.DG_colors(2))
                yline(ax_peak_CSD,depth(app.DG_layers{app.UseShank}(4)), '--',"Color", app.DG_colors(4))
                xline(ax_peak_CSD,0, '--')
                xlabel(ax_peak_CSD,"CSD")
                ylabel(ax_peak_CSD, "Depth (µm)")
                fontsize(ax_peak_CSD,10,"points")


            %% plot rate and % of DS types
            colororder(ax_rate, ["k", "k"])
            hold(ax_rate, "on")
            yyaxis(ax_rate, "left")
            bar(ax_rate,1, length(app.DS_struct{app.UseShank}.time)/app.recordingDur, "FaceColor","k", "EdgeColor","k")
            yyaxis(ax_rate, "right")
            bar(ax_rate,1, 100, "FaceColor","k", "EdgeColor","k")
            for i = 1:3
                yyaxis(ax_rate, "left")
                bar(ax_rate,i+1, sum(DStype==i)/app.recordingDur, "FaceColor", app.DG_colors(i), "EdgeColor", app.DG_colors(i))
                yyaxis(ax_rate, "right")
                bar(ax_rate,i+1, sum(DStype==i)/length(app.DS_struct{app.UseShank}.time)*100, "FaceColor", app.DG_colors(i), "EdgeColor", app.DG_colors(i))
            end
            xticks(ax_rate,1:4)
            xticklabels(ax_rate,{"All", "DS_L", "DS_M", "DS_{LM}"})
            yyaxis(ax_rate, "left")
            ylabel(ax_rate,"Rate (Hz)")
            yyaxis(ax_rate, "right")
            ylabel(ax_rate,"% of events")
            fontsize(ax_rate,10,"points")

            %% plot csd/waveform for the selected shank (mean or individual)
            if Ismean == 1
                for j = 1:shanks
                    if strcmp(app.Switch_2.Value, "CSD")
                        for i =1:3
                            mean_csd = mean(app.CSD{app.UseShank}(:,:,DStype == i, shank_vec(j)),3);
                            PlotCSDmat(app, ax_DS{j,i}, time,depth, mean_csd, colorlim)
                            clim(ax_DS{j,i}, [-app.climEditField_2.Value app.climEditField_2.Value])
                            title(ax_DS{j,i}, strcat(app.DS_type(i), " sh", num2str(shank_vec(j))))
                            box(ax_DS{j,i}, "off")
                            xlabel(ax_DS{j,i},"Time (ms)")
                            ylabel(ax_DS{j,i},"Depth (µm)")
                            colormap(ax_DS{j,i}, app.HeatmapDropDown_2.Value)
                            fontsize(ax_DS{j,i},10,"points")
                        end

                    else
                        meanWave = app.MeanWaveForm{app.UseShank};
                        for i =1:3
                            if IsmultiCh == 0
                                plot(ax_DS{j,i},time, meanWave(:,app.DG_layers{shank_vec(j)}(4), app.UseShank,i), "Color", app.DG_colors(i), "LineWidth", 4)
                                ylabel(ax_DS{j,i},"Amplitude (µV)")
                            else
                                for c = 1:N_channels % add case when each channel is plotted
                                    plot(ax_DS{j,i}, time,  meanWave(:,c, app.UseShank,i)+c*shift, 'k-')
                                    hold(ax_DS{j,i}, 'on')
                                    for l = 1:4
                                        if ~isempty(app.DS_struct{j}) && c == app.DG_layers{j}(l)
                                            plot(ax_DS{j,i}, time,  meanWave(:,app.DG_layers{shank_vec(j)}(l), app.UseShank,i)+c*shift, '-', 'Color', app.DG_colors(l))
                                        end
                                    end
                                end

                            end

                            title(ax_DS{j,i},strcat(app.DS_type(i), " sh", num2str(shank_vec(j))))
                            box(ax_DS{j,i}, "off")
                            xlabel(ax_DS{j,i},"Time (ms)")
                            
                            fontsize(ax_DS{j,i},10,"points")
                        end

                    end

                end

            else


                %% ax 1 - plot waveform of DS - single channel or multi-chanel
                timing = app.DS_struct{app.UseShank}.time(app.DS_idxToPlot(currentDS));    % get timing of selected DS event      
                
                t0 = timing-win;
                if t0 < 1
                    t0 = 1;
                end

                t1 = timing+win;

                if t1 > N_points
                    t1 = N_points;
                end
                
                
                for j = 1:shanks
                    hold(ax_DS{j,1}, "on")
                    if IsmultiCh == 0
                        plot(ax_DS{j,1},(t0:t1)./app.sampleRate, app.LFPmatFixed(t0:t1, app.DG_layers{app.UseShank}(4), shank_vec(j)), 'Color', app.DG_colors(idx), "LineWidth",2)
                    else
                        for c = 1:N_channels % add case when each channel is plotted
                          plot(ax_DS{j,1}, (t0:t1)./app.sampleRate, app.LFPmatFixed(t0:t1, c, shank_vec(j))+c*shift, 'k-')

                            for l = 1:4
                                if ~isempty(app.DS_struct{j}) && c == app.DG_layers{j}(l)
                                    plot(ax_DS{j,1}, (t0:t1)./app.sampleRate, app.LFPmatFixed(t0:t1, c, shank_vec(j))+c*shift, '-', 'Color', app.DG_colors(l))
                                end
                            end
                        end
                        xline(ax_DS{j,1},round((t1-t0)/2), "r--")
                    end
                    fontsize(ax_DS{j,1},10,"points")
                    xlabel(ax_DS{j,1}, "Time (s)")
                    ylabel(ax_DS{j,1}, "Amplitude (\muV)")
                    box(ax_DS{j,1}, "off")
                    xlim(ax_DS{j,1}, [t0 t1]./app.sampleRate)
                    xline(ax_DS{j,1}, app.DS_struct{app.UseShank}.time(app.DS_idxToPlot(currentDS))/app.sampleRate+app.DS_struct{app.UseShank}.width(app.DS_idxToPlot(currentDS))/1000/2, 'k-')
                    xline(ax_DS{j,1}, app.DS_struct{app.UseShank}.time(app.DS_idxToPlot(currentDS))/app.sampleRate-app.DS_struct{app.UseShank}.width(app.DS_idxToPlot(currentDS))/1000/2, 'k-')
                    title(ax_DS{j,1},strcat("width= ", num2str(round(app.DS_struct{app.UseShank}.width(app.DS_idxToPlot(currentDS)))), "ms"))
                end

                %% ax 2 - plot CSD @ DS peak
                if showCoincidence == 0
                
                for j = 1:shanks
                    hold(ax_DS{j,2}, "on")
                    if app.DG_layers{shank_vec(j)}(1) > 0
                        yline(ax_DS{j,2},depth(app.DG_layers{shank_vec(j)}(1)), '--',"Color", app.DG_colors(1))
                        yline(ax_DS{j,2},depth(app.DG_layers{shank_vec(j)}(2)), '--',"Color", app.DG_colors(2))
                        yline(ax_DS{j,2},depth(app.DG_layers{shank_vec(j)}(4)), '--',"Color", app.DG_colors(4))
                    end
                    xline(ax_DS{j,2}, 0, '--')
                    plot(ax_DS{j,2}, app.CSD{app.UseShank}(:,PeakTime, app.DS_idxToPlot(currentDS), shank_vec(j)), depth, "o-", "Color", app.DG_colors(idx), "LineWidth",2)
                    box(ax_DS{j,2}, "off")
                    xlabel(ax_DS{j,2}, "CSD at DS peak")
                    ylabel(ax_DS{j,2}, "Depth (\mum)")
                    fontsize(ax_DS{j,2},10,"points")
                end

                else
                        for j = 1:shanks
                            hold(ax_DS{j,2}, "on")
                            for i = 1:2
                                plot(ax_DS{j,2},time,app.CSD{app.UseShank}(app.DG_layers{shank_vec(j)}(i),:,app.DS_idxToPlot(currentDS), shank_vec(j))-i/5, "-", "Color", app.DG_colors(i))
                                yline(ax_DS{j,2},-i/5, 'k--')
                            end    
                                xline(ax_DS{j,2}, app.DS_struct{app.UseShank}.width(app.DS_idxToPlot(currentDS))/2, 'k-')
                                xline(ax_DS{j,2}, -app.DS_struct{app.UseShank}.width(app.DS_idxToPlot(currentDS))/2, 'k-')
                        
                        yticks(ax_DS{j,2}, [-2/5 -1/5])
                        yticklabels(ax_DS{j,2}, {"MML", "OML"})
                        xlabel(ax_DS{j,2}, "Time from DS peak (ms)")
                        fontsize(ax_DS{j,2},10,"points")
                        end
                 end


                %% ax 3 - plot CSD
                %app.LFPmatFixed{app.UseShank}(app.DS_idxToPlot(currentDS))

                for j = 1:shanks
                csd = app.CSD{app.UseShank}(:,:,app.DS_idxToPlot(currentDS), shank_vec(j));
                PlotCSDmat(app, ax_DS{j,3}, time,depth, csd, colorlim)
                colormap(ax_DS{j,3}, app.HeatmapDropDown_2.Value)
                fontsize(ax_DS{j,3},10,"points")
                hold(ax_DS{j,3}, "on")

                if app.DG_layers{shank_vec(j)}(1) > 0
                    yline(ax_DS{j,3},depth(app.DG_layers{shank_vec(j)}(1)), '--',"Color", app.DG_colors(1), "LineWidth",1)
                    yline(ax_DS{j,3},depth(app.DG_layers{shank_vec(j)}(2)), '--',"Color", app.DG_colors(2), "LineWidth",1)
                    yline(ax_DS{j,3},depth(app.DG_layers{shank_vec(j)}(4)), '--',"Color", app.DG_colors(4), "LineWidth",1)
                end
                    %xline(ax_DS{j,3}, 0, "k--")
                    xline(ax_DS{j,3}, app.DS_struct{app.UseShank}.width(app.DS_idxToPlot(currentDS))/2, 'k-')
                    xline(ax_DS{j,3}, -app.DS_struct{app.UseShank}.width(app.DS_idxToPlot(currentDS))/2, 'k-')
                
                end
            end


        end

        function Reset(app)


            %clear variables
            app.LFPmat= [];
            app.LFPmatFixed = [];
            app.LFPmatFiltered = [];
            app.DS_LFP = [];
            app.DS_struct = [];
            app.PCA_res = [];
            app.Classify_res_raw = [];
            app.Classify_res_final = [];
            app.CSD = [];
            app.DG_layers = [];
            
            app.MeanLFP_DS = [];

            %reset lamps & knob
            app.Lamp.Color = [0.55,0.50,0.50];
            app.Lamp_2.Color = [0.55,0.50,0.50];
            app.Lamp_4.Color = [0.55,0.50,0.50];


            %reset buttons and checkboxes
            app.rawLFPButton.Value = 1;
            app.fixedLFPButton.Enable ="off";

            app.rawLFPButton_2.Value = 1;
            app.fixedLFPButton_2.Enable ="off";

            app.PlotmeanCheckBox.Value = 0;
            app.PlotmeanCheckBox_2.Value = 0;

            % clear figures and text areas
            app.TextArea.Value ="";
            tiledlayout(app.LFPViewerPanel, 2, 1)
            tiledlayout(app.PutativeDSViewerPanel, 2, 1)
            tiledlayout(app.ClusterViewerPanel, 2, 1)
            tiledlayout(app.DSViewerPanel, 2, 1)
            tiledlayout(app.ProbeLayoutPanel, 2, 1)
            tiledlayout(app.ProbeLayoutPanel_2, 2, 1)
            tiledlayout(app.ProbeLayoutPanel_3, 2, 1)
            tiledlayout(app.DSViewerPanel, 2, 1)


        end

        function PlotDSparams(app)

            Layout = tiledlayout(app.PutativeDSViewerPanel,2,2);
            %plot amplitude distribution
            ax = nexttile(Layout);
            plot(ax,app.DS_struct{app.UseShank}.amp, 'o', 'Color', app.DG_colors(4), "MarkerFaceColor", app.DG_colors(4))
            ylabel(ax,"Amplitude (\muV)")
            xlabel(ax,"Event #")
            hold(ax, "on")
            yline(ax,mean(app.DS_struct{app.UseShank}.amp), "--")
            box(ax,"off")
            fontsize(ax,10,"points")

            %plot width distribution
            ax = nexttile(Layout);
            plot(ax,app.DS_struct{app.UseShank}.width, 'o', 'Color', app.DG_colors(4), "MarkerFaceColor", app.DG_colors(4))
            ylabel(ax,"Width (ms)")
            xlabel(ax,"Event #")
            hold(ax, "on")
            yline(ax,mean(app.DS_struct{app.UseShank}.width), "--")
            box(ax,"off")
            fontsize(ax,10,"points")


            %plot timeline with detected events

            ax = nexttile(Layout);
            [Npoints, ~,~] = size(app.LFPmat);
            time = (1:Npoints)./app.sampleRate;
            binaryDS = zeros(Npoints,1);
            binaryDS(app.DS_struct{app.UseShank}.time) = 1;
            plot(ax,time, binaryDS, '-', 'Color', app.DG_colors(4))
            yticks(ax,[0 1])
            xlabel(ax, "Time (s)")
            ylabel(ax,"DS detected")
            box(ax,"off")
            fontsize(ax,10,"points")
            if isfield(app.config, 'session_length') == 1
                hold(ax,"on")
                for i = 1:length(app.config.session_length)-1
                    xline(ax, sum(app.config.session_length(1:i))./app.sampleRate, "r--", "LineWidth", 3)
                    disp(sum(app.config.session_length(1:i))./app.sampleRate)
                end
            end
            xlim(ax, [-2 app.recordingDur+2])


            %plot amp variance distribution across channels
            ax = nexttile(Layout);
            plot(ax,diff(app.DS_struct{app.UseShank}.time)./1000, 'o', 'Color', app.DG_colors(4), "MarkerFaceColor", app.DG_colors(4))
            hold(ax, "on")
            yline(ax,mean(diff(app.DS_struct{app.UseShank}.time))/1000, '--')
            ylabel(ax, "Time (s)")
            xlabel(ax, "Inter DS interval")
            yscale(ax, 'log')
            box(ax,"off")
            fontsize(ax,10,"points")

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)

            Reset(app);
            app.TabOpen = 0;
            %clear figure
            app.Lamp.Color = app.red_color;
            pause(0.3)
            [file, app.workdir] = uigetfile("*.mat");

            %% load config file and get probe parameters
            if exist(strcat(app.workdir, "/config.mat"), 'file')
                load(strcat(app.workdir, "/config.mat"));
                app.config = config;
            else
                errordlg("Config file not found!")
                error("Config file not found!")
            end

            [app.N_channel, app.N_shank] = size(app.config.channel_map);
            app.ChannelSpinner.Limits = [1 app.N_channel];
            app.ShankSpinner.Limits = [1 app.N_shank];
            app.ConnectedButton.Value = 1;
            app.config.DeadChannel = zeros(size(app.config.channel_map));
            if isfield(app.config, 'spacing') == 0
                app.config.spacing = app.config.y_coords(2)- app.config.y_coords(1);
            end
            app.DS_struct = cell(app.N_shank,1);

            % plot probe layout based on channel map
            PlotProbe(app)


            % init vector for DG layers (oml, mml, -1, gcl lower mml lower oml)
            for i = 1:app.N_shank
                app.DG_layers{i} = repmat(-1, 6,1);
                app.MeanLFP_DS{i} = [];
            end


            %% load ephys data - channel by channel or .bin matrix

            app.sampleRate = app.config.fs;
            %disp(strcat("Loading ", app.workdir, "\", file))
            app.SelectdataLabel.Text = "Loading...";
            pause(0.3)
            app.LFPmat = LoadEphysData_Single(strcat(app.workdir, "\", file), app.N_channel, app.N_shank, app.config.channel_map);

            %% now bandpass filter LFP
            disp("Filtering data...")
            pause(0.3)
            app.SelectdataLabel.Text = "Filtering...";
            app.preparams.bp = [app.LowcutoffHzEditField.Value app.HighcutoffHzEditField.Value];
            for shank = 1:app.N_shank
                disp(strcat("Shank ", num2str(shank) ))
                app.LFPmat(:,:,shank) = FilterLFP(app.LFPmat(:,:,shank), app.preparams.bp, app.sampleRate);
            end

            disp("Filtering finished!")
            app.SelectdataLabel.Text = "Select data!";
            pause(0.3)
            % specific channel reading for the Ewell lab
            app.Lamp.Color = app.green_color;

            [Npoints, ~, ~] = size(app.LFPmat);
            app.recordingDur = Npoints/app.sampleRate;
            %plot LFP
            PlotLFP(app);


        end

        % Callback function: DSSpinner, DSSpinner_2, LFPtoplotButtonGroup, 
        % ...and 9 other components
        function PlotButtonPushed(app, event)

            switch app.TabOpen
                case 0
                    PlotLFP(app);
                case 1
                    app.UseShank = app.UseshankSpinner.Value;
                    if strcmp(app.Switch_3.Value, "LFP") == 1
                        if app.PlotmeanCheckBox.Value == 0
                            PlotLFP(app);
                        else
                            PlotMeanLFP(app);
                        end
                    else
                        PlotDSparams(app);
                    end
                case 2
                    app.UseShank = app.UseshankSpinner_2.Value;
                    PlotPCAClusters(app);
                case 3
                    app.UseShank = app.UseshankSpinner_3.Value;
                    PlotClassification(app);
                case 4
                    app.UseShank = app.UseshankSpinner_4.Value;
                    PlotClassification(app);
            end
        end

        % Button pushed function: DetectButton
        function DetectButtonPushed(app, event)
            %% detect dead channels
            app.TabOpen = 0;
            app.Lamp_2.Color = app.red_color;
            pause(0.3)
            app.preparams.neighbors = app.ofneighborsSpinner.Value;
            app.preparams.similarity = app.SimilaritythresholdEditField.Value;
            disp("Detecting dead channels...")
            [app.config.DeadChannel, app.SimilarityMatrix] = DetectDeadChannels(app.LFPmat, app.ofneighborsSpinner.Value, app.SimilaritythresholdEditField.Value);
            app.Lamp_2.Color = app.green_color;
            PlotLFP(app);
            PlotProbe(app);
            disp("Detection finished!")
        end

        % Value changed function: ChannelSpinner, ShankSpinner
        function ShankSpinnerValueChanged(app, event)


            app.SelectedChannel(1) = app.ShankSpinner.Value;
            app.SelectedChannel(2) = app.ChannelSpinner.Value;

            % app.SelectedChannel(2)
            PlotProbe(app);
            PlotLFP(app);
            if app.config.DeadChannel(app.SelectedChannel(2), app.SelectedChannel(1)) == 1
                app.DeadButton.Value = 1;
            else
                app.ConnectedButton.Value = 1;
            end


        end

        % Selection changed function: LabelselectedButtonGroup
        function LabelselectedButtonGroupSelectionChanged(app, event)
            selectedButton = app.LabelselectedButtonGroup.SelectedObject;
            app.SelectedChannel(1) = app.ShankSpinner.Value;
            app.SelectedChannel(2) = app.ChannelSpinner.Value;
            if app.SelectedChannel(1) ~= -1
                if strcmp(selectedButton.Text, 'Dead') == 1
                    app.config.DeadChannel(app.SelectedChannel(2), app.SelectedChannel(1)) =1;
                else
                    app.config.DeadChannel(app.SelectedChannel(2), app.SelectedChannel(1)) =0;
                end
                PlotLFP(app);
                PlotProbe(app);
            end
        end

        % Button pushed function: FixchannelsButton
        function FixchannelsButtonPushed(app, event)
            app.TabOpen = 0;
            app.LFPmatFixed = FixDeadChannels(app.LFPmat, app.config.DeadChannel);
            disp("Dead channels are fixed!")
            app.fixedLFPButton.Enable = "on";
            app.fixedLFPButton.Value = 1;
            PlotLFP(app);
        end

        % Callback function
        function FilterButtonPushed(app, event)
            app.TabOpen = 0;


            app.filteredLFPButton.Enable = "on";
        end

        % Button pushed function: DetectDSButton
        function DetectDSButtonPushed(app, event)
            app.TabOpen = 1;
            disp("Detecting DS...")
            app.Lamp_4.Color = app.red_color;
            pause(0.3)
            app.UseShank = app.UseshankSpinner.Value;
            app.det_params.N = app.ThresholdzscoreEditField.Value;
            app.det_params.min_width = app.MinwidthmsEditField.Value*app.sampleRate/1000;
            app.det_params.max_width = app.MaxwidthmsEditField.Value*app.sampleRate/1000;
            app.det_params.max_amp = app.MaxampuVEditField.Value;
            app.det_params.fs = app.sampleRate;
            app.det_params.ref = app.ReferencechEditField.Value;

            selectedButton = app.ChannelselectionButtonGroup.SelectedObject;

            if strcmp(selectedButton.Text, 'Manual')
                app.det_params.channel = app.UsechannelEditField.Value;

                LogString = "Detection channel was selected manually." + newline;
            else
                app.det_params.channel = -1;
                LogString = "Channel was estimated automatically." + newline;
            end

            if isempty(app.LFPmatFixed)
                app.LFPmatFixed = app.LFPmat;
            end
            app.DS_struct{app.UseShank} = DetectDS(app.LFPmatFixed(:,:,app.UseShank), app.det_params);
            app.DS_struct{app.UseShank}.width = app.DS_struct{app.UseShank}.width./app.sampleRate.*1000; % convert width to ms
            app.DS_struct{app.UseShank}.timeMS = app.DS_struct{app.UseShank}.time./app.sampleRate.*1000; % timing in ms
            LogString = LogString + "Detecting on shank " + num2str(app.UseShank) + " channel " + num2str(app.DS_struct{app.UseShank}.channel) + newline;
            disp("Detection finished!...")
            app.Lamp_4.Color = app.green_color;
            app.DSSpinner.Limits = [1 length(app.DS_struct{app.UseShank}.time)];
            app.DG_layers{app.UseShank}(4) = app.DS_struct{app.UseShank}.channel;
            app.fixedLFPButton_2.Value = 1;
            PlotProbe(app);
            PlotLFP(app);

            % log results
            LogString = LogString + "# of detected events: " + num2str(length(app.DS_struct{app.UseShank}.time)) + newline;
            LogString = LogString + "Event rate: " + num2str(round(length(app.DS_struct{app.UseShank}.time)/app.recordingDur,2)) + " (Hz)" + newline;
            LogString = LogString + "Amplitude range: [" + num2str(round(min(app.DS_struct{app.UseShank}.amp))) + "-" + num2str(round(max(app.DS_struct{app.UseShank}.amp))) + "] µV"+ newline;
            LogString = LogString + "Mean amplitude: " + num2str(round(mean(app.DS_struct{app.UseShank}.amp))) + " µV" + newline;
            LogString = LogString + "Width range: [" + num2str(round(min(app.DS_struct{app.UseShank}.width))) + "-" + num2str(round(max(app.DS_struct{app.UseShank}.width))) + "] ms"+ newline;
            LogString = LogString + "Mean half-width: " + num2str(round(mean(app.DS_struct{app.UseShank}.width))) + " ms" + newline;
            app.TextArea.Value = LogString;
        end

        % Button down function: DetectDSTab
        function DetectDSTabButtonDown(app, event)
            app.SelectedChannel = [-1 -1];
            app.UseshankSpinner.Limits = [1 app.N_shank];
            app.UsechannelEditField.Limits= [1 app.N_channel];
            app.TabOpen = 1;
            app.fixedLFPButton_2.Enable= "on";

        end

        % Button down function: PreprocessLFPTab
        function PreprocessLFPTabButtonDown(app, event)
            app.UseShank = -1;
            app.TabOpen = 0;
        end

        % Callback function
        function RunselectedButtonPushed(app, event)

        end

        % Value changing function: ShankSpinner
        function ShankSpinnerValueChanging(app, event)
            %unused


        end

        % Button pushed function: ComputeButton
        function ComputeButtonPushed(app, event)
            %% first calculate csd around DS events on each channel and shank
            app.TabOpen = 2;
            kern_alpha=(app.KernellengthEditField.Value-1)/(2*app.KernelsigmaEditField.Value);
            app.classifier_params.kern_win = gausswin(app.KernellengthEditField.Value, kern_alpha); % gaussian filter
            app.classifier_params.window =  app.TimewindowmsEditField_2.Value*app.sampleRate/1000;
            app.classifier_params.spacing = app.config.spacing;
            app.classifier_params.use_channels = cell(app.N_shank,1);
            app.Lamp_5.Color = app.red_color;
            pause(.3);
            app.UseShank = app.UseshankSpinner_2.Value;
            if length(app.DS_struct) < app.UseShank
                errordlg("DSs were not assigned to this shank yet. Go to detection tab.", "Error");
                error("Error! DSs were not assigned to this shank yet. Go to detection tab."); 
            end
          if isempty(app.DS_struct{app.UseShank})
                errordlg("DSs were not assigned to this shank yet. Go to detection tab.", "Error");
                error("Error! DSs were not assigned to this shank yet. Go to detection tab.");        
           end
            % compute CSD
            disp("Calculating CSD...")
            [app.CSD{app.UseShank}, app.DS_LFP{app.UseShank}] = Compute_StandardCSD(app.LFPmatFixed, app.DS_struct{app.UseShank}.time, app.classifier_params);
            disp("Done!")
            app.Lamp_5.Color = app.green_color;
        end

        % Callback function
        function ClusterButtonPushed(app, event)
            app.TabOpen = 2;

            %get new indices after manual selection
            H = drawpolygon(app.ClusterAx, "Color", [.5 .5 .5]);
            IN = inpolygon(app.PCA_res{app.UseShank}.coords(:,1), app.PCA_res{app.UseShank}.coords(:,2), H.Position(:,1),H.Position(:,2));
            idx(IN) = 1;
            idx(~IN) = 2;

            %estimate molecular layer
            [app.EstimatedML{app.UseShank}, app.DS_struct{app.UseShank}.clust_idx] = GetMolecularLayers(app.CSD{app.UseShank}(:,:,:,app.UseShank), idx, app.DS_struct{app.UseShank}.channel);
            app.OMLEditField.Value = app.EstimatedML{app.UseShank}.OML;
            app.MMLEditField.Value = app.EstimatedML{app.UseShank}.MML;
            app.DG_layers{app.UseShank}(1) = app.EstimatedML{app.UseShank}.OML;
            app.DG_layers{app.UseShank}(2) = app.EstimatedML{app.UseShank}.MML;

            %plot results
            PlotProbe(app);
            PlotPCAClusters(app);

        end

        % Button down function: EstimateMLTab
        function EstimateMLTabButtonDown(app, event)
            app.TabOpen = 2;
            app.UseshankSpinner_2.Limits = [1 app.N_shank];
            app.UseshankSpinner_2.Value = app.UseshankSpinner.Value;
            app.ToEditField.Value = app.N_channel-1;
            app.FromEditField.Value = 2;
        end

        % Button down function: ClassifyDSTab_2
        function ClassifyDSTab_2ButtonDown(app, event)
            app.TabOpen = 3;
            app.UseshankSpinner_3.Limits = [1 app.N_shank];
            app.UseshankSpinner_3.Value = app.UseshankSpinner_2.Value;
            app.OMLEditField_2.Value = app.DG_layers{app.UseShank}(1);
            app.MMLEditField_2.Value = app.DG_layers{app.UseShank}(2);
            app.HILEditField.Value = app.DS_struct{app.UseShank}.channel;
            app.PlotmeanCheckBox_2.Value =1;
            app.DSSpinner_2.Value = 1;
        end

        % Callback function
        function MethodButtonGroupSelectionChanged(app, event)
                %unused
    
        end

        % Button pushed function: ClassifyButton
        function ClassifyButtonPushed(app, event)
            app.TabOpen = 3;
            pause(.3)
            app.UseShank = app.UseshankSpinner_3.Value;
            app.OMLEditField_2.Value = app.DG_layers{app.UseShank}(1);
            app.MMLEditField_2.Value = app.DG_layers{app.UseShank}(2);
            app.HILEditField.Value = app.DG_layers{app.UseShank}(4);
            params.method = "Default";
            params.OML = app.DG_layers{app.UseShank}(1);
            params.MML = app.DG_layers{app.UseShank}(2);
            params.integral_win = NaN;
            params.integral_thresh = NaN; % integral method is not used anymore

            if length(app.CSD) < app.UseShank
                errordlg("DSs were not assigned to this shank yet.", "Error");
                error("Error! DSs were not assigned to this shank yet."); 
            end
            if isempty(app.CSD{app.UseShank})  
                errordlg("DSs were not assigned to this shank yet.", "Error");
                error("Error! DSs were not assigned to this shank yet.");        
            end
            % first get DS1s and 2s, and consider every double-sink event
            % as DS3
            app.Classify_res_raw{app.UseShank} = DS_Classification(params, app.CSD{app.UseShank}(:,:,:,app.UseShank));
                        
            % next refine classification based on the relative current sink
            % cut-off
            app.Classify_res_final{app.UseShank}  = AnalyzeSinks(app.DG_layers{app.UseShank}, app.CSD{app.UseShank}(:,:,:,app.UseShank), app.SinkcutoffEditField.Value, app.Classify_res_raw{app.UseShank}.DStype, 0);
        
            disp("Calculating mean waveform...")
            app.MeanWaveForm{app.UseShank} = GetMeanWaveForm(app, app.Classify_res_final{app.UseShank}.DStype);

            %% visualize contribution and cut-off
            t= tiledlayout(app.ContributionViewerPanel, 2,1);

            ax = nexttile(t);
            histogram(ax,app.Classify_res_final{app.UseShank}.score, "FaceColor", app.DG_colors(3), "EdgeColor", app.DG_colors(3), "Normalization","percentage", "BinEdges", -1:0.1:1)
            box(ax, "off")
            xlabel(ax, "Relative current sink")
            ylabel(ax, "Probability")
            hold(ax, "on")
            xline(ax,app.Classify_res_final{app.UseShank}.cut_off, "--")
            xline(ax,-app.Classify_res_final{app.UseShank}.cut_off, "--")
            fontsize(ax,10,"points")
            ax = nexttile(t);
            [y,x] = ecdf(app.Classify_res_final{app.UseShank}.score);
            hold(ax, "on")
            plot(ax,x,y, "Color",app.DG_colors(3), "LineWidth",3)
            %plot(ax,[0 1], [0 1], "k--", "LineWidth",3)
            xlabel(ax,"Relative current sink")
            ylabel(ax,"Proortion")
            fontsize(ax,10,"points")

            % plot probe layout & results
            PlotClassification(app);

        end

        % Button pushed function: ClusterButton
        function ClusterButtonPushed2(app, event)
            %% read out the method
            app.UseShank = app.UseshankSpinner_2.Value;
            selectedMethod= app.MethodButtonGroup.SelectedObject.Text;
            idx = zeros(length(app.DS_struct{app.UseShank}.clust_idx),1);
            switch selectedMethod
                case "PCX" % use first principal component to select the most different DSs (low and high end)
                    Tail  = app.ileEditField.Value;

                    %calculate threshold based on %ile of PC1pos and PC1
                    %neg events
                    PC1neg = app.PCA_res{app.UseShank}.coords(: ,app.PCX) < 0;
                    PC1pos = app.PCA_res{app.UseShank}.coords(: ,app.PCX) > 0;
                    ThreshNeg = prctile(app.PCA_res{app.UseShank}.coords(PC1neg ,app.PCX), 100-Tail);
                    ThreshPos = prctile(app.PCA_res{app.UseShank}.coords(PC1pos, app.PCX), Tail);

                    % assign tails to clusters
                    idx(app.PCA_res{app.UseShank}.coords(: ,app.PCX) < ThreshNeg) = 1;
                    idx(app.PCA_res{app.UseShank}.coords(: ,app.PCX) > ThreshPos) = 2;

                case "Manual"
                    for i = 1:2
                        title(app.ClusterAx, strcat("Select clust ", num2str(i), "!"))
                        H = drawpolygon(app.ClusterAx, "Color", [.5 .5 .5]);
                        IN = inpolygon(app.PCA_res{app.UseShank}.coords(:,app.PCX), app.PCA_res{app.UseShank}.coords(:,app.PCY), H.Position(:,1),H.Position(:,2));
                        idx(IN) = i;
                    end
            end

            % now use the new indices to recalculate CSD/waveform
            [app.EstimatedML{app.UseShank}, app.DS_struct{app.UseShank}.clust_idx] = GetMolecularLayers(app.CSD{app.UseShank}(:,:,:,app.UseShank), idx, app.DS_struct{app.UseShank}.channel);
            app.OMLEditField.Value = app.EstimatedML{app.UseShank}.OML;
            app.MMLEditField.Value = app.EstimatedML{app.UseShank}.MML;
            app.DG_layers{app.UseShank}(1) = app.EstimatedML{app.UseShank}.OML;
            app.DG_layers{app.UseShank}(2) = app.EstimatedML{app.UseShank}.MML;
            %plot results
            PlotProbe(app);
            PlotPCAClusters(app);

        end

        % Button pushed function: AddlayersButton
        function lowerOMLEditFieldValueChanged(app, event)
            app.DG_layers{app.UseShank}(6) = app.lowerOMLEditField.Value;
            app.DG_layers{app.UseShank}(5) = app.lowerMMLEditField.Value;
            PlotProbe(app);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)

            probe.N_shank = app.N_shank;
            probe.N_channel = app.N_channel;
            probe.layout = app.config.channel_map;
            probe.x_coords= app.config.x_coords;
            probe.y_coords = app.config.x_coords;
            probe.spacing = app.config.spacing;
            sampleRate = app.sampleRate;
            DG_layers = app.DG_layers;
            DS_struct = app.DS_struct;
            PCA_results= app.PCA_res;
            Classify_results_raw = app.Classify_res_raw;
            Classify_results = app.Classify_res_final;
            CSD = app.CSD;
            recordingDur = app.recordingDur;
            params.preprocessing = app.preparams;
            params.DSdetection = app.det_params;
            params.Clustering = app.classifier_params;

            file_name = strcat(app.FilenameEditField.Value, ".mat");

            if exist(strcat(app.workdir, file_name),"file")
                delete(strcat(app.workdir, file_name))
            end
            save(strcat(app.workdir, file_name), "probe", "DG_layers", "DS_struct", "PCA_results", "Classify_results_raw",  "Classify_results", "CSD", "params", "recordingDur" , "sampleRate");
            msgbox(strcat("Results were saved as ", app.workdir, file_name))

        end

        % Button pushed function: EditMLButton
        function AddmanuallyButtonPushed(app, event)
            app.DG_layers{app.UseShank}(1) = app.OMLEditField_2.Value;
            app.DG_layers{app.UseShank}(2) = app.MMLEditField_2.Value;
        end

        % Callback function
        function DownsampleCheckBoxValueChanged(app, event)
            value = app.DownsampleCheckBox.Value;
            if value == 1
                app.HzEditField.Enable = "on";
            else
                app.HzEditField.Enable = "off";
            end

        end

        % Callback function
        function SinglefileCheckBoxValueChanged(app, event)
            value = app.SinglefileCheckBox.Value;
            if value == 1
                app.ChannelfileEditField.Enable = "off";
                app.DownsampleCheckBox.Value = 0;
                app.HzEditField.Enable = "off";
                app.DownsampleCheckBox.Enable  ="off";
            else
                app.ChannelfileEditField.Enable = "on";
                app.HzEditField.Enable = "on";
                app.DownsampleCheckBox.Enable  ="on";
                app.DownsampleCheckBox.Value = 1;
                app.HzEditField.Value = 2000;
            end
        end

        % Callback function
        function PCXSpinnerValueChanged(app, event)

            PlotPCAClusters(app);
        end

        % Button pushed function: RunPCAButton
        function RunPCAButtonPushed(app, event)
            app.TabOpen=2 ;
            app.UseShank = app.UseshankSpinner_2.Value;
            % run PCA (only on one shank)
            disp("Clustering events...")
            chs= app.FromEditField.Value:app.ToEditField.Value;
            app.Lamp_5.Color = app.red_color;
            pause(.3)
            %remove non-used channels
            if length(app.CSD) < app.UseShank
                errordlg("DSs were not assigned to this shank yet.", "Error");
                error("Error! DSs were not assigned to this shank yet."); 
            end
           if isempty(app.CSD{app.UseShank})
                errordlg("DSs were not assigned to this shank yet.", "Error");
                error("Error! DSs were not assigned to this shank yet.");        
           end

            app.PCA_res{app.UseShank} = PCA_on_CSD(app.CSD{app.UseShank}(:,:,:,app.UseShank), chs);
            app.PCXSpinner.Value = 1;
            app.PCYSpinner.Value = 2;
            app.PCXSpinner.Limits = [1 length(app.PCA_res{app.UseShank}.explained)];
            app.PCYSpinner.Limits = [1 length(app.PCA_res{app.UseShank}.explained)];

            % cluster dentate spike and estimate molecular layer
            [app.EstimatedML{app.UseShank}, app.DS_struct{app.UseShank}.clust_idx] = GetMolecularLayers(app.CSD{app.UseShank}(:,:,:,app.UseShank), app.PCA_res{app.UseShank}.idx, app.DS_struct{app.UseShank}.channel);

            %% correct OML/MML due when not all channel is used for PCA
            %all_ch = 1:app.N_channel;
            %app.EstimatedML{app.UseShank}.OML =  app.EstimatedML{app.UseShank}.OML;
            %app.EstimatedML{app.UseShank}.MML = app.EstimatedML{app.UseShank}.MML;
            app.OMLEditField.Value = app.EstimatedML{app.UseShank}.OML;
            app.MMLEditField.Value = app.EstimatedML{app.UseShank}.MML;
            app.HILEditField_2.Value = app.DS_struct{app.UseShank}.channel;
            app.DG_layers{app.UseShank}(1) = app.EstimatedML{app.UseShank}.OML;
            app.DG_layers{app.UseShank}(2) = app.EstimatedML{app.UseShank}.MML;
            app.NClustEst{app.UseShank} = length(unique(app.DS_struct{app.UseShank}.clust_idx));
            disp("Calculating mean waveform...")
            app.MeanWaveForm{app.UseShank} = GetMeanWaveForm(app, app.DS_struct{app.UseShank}.clust_idx);%calculate mean waveform for each DS type
            % now plot probe layout and PCA results (CSD or LFP)
            PlotProbe(app);
            PlotPCAClusters(app);
            app.Lamp_5.Color = app.green_color;
        end

        % Callback function
        function RunButtonPushed(app, event)
            app.UseShank = app.UseshankSpinner_4.Value;
            app.TabOpen = 4;


        end

        % Callback function: DStoplotButtonGroup, PlotmeanCheckBox_2
        function DStoplotButtonGroup_2SelectionChanged(app, event)

            if app.TabOpen == 4
                selectedButton = app.DStoplotButtonGroup_2.SelectedObject.Text;
            else
                selectedButton = app.DStoplotButtonGroup.SelectedObject.Text;
            end

            switch selectedButton
                case "DS1"
                    idx = 1;

                case "DS2"
                    idx = 2;

                case "DS3"
                    idx =3;
            end

                app.UseShank = app.UseshankSpinner_3.Value;
                app.DS_idxToPlot = find(app.Classify_res_final{app.UseShank}.DStype == idx);
                app.DSSpinner_2.Limits = [1 length(app.DS_idxToPlot)];
   

        end

        % Callback function
        function ClassifyDSTabButtonDown(app, event)
            app.UseshankSpinner_4.Limits = [1 app.N_shank];
            app.UseshankSpinner_4.Value = app.UseshankSpinner_3.Value;
            app.CutoffEditField.Limits = [0 1];
            app.DSSpinner_3.Value = 1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PreprocessLFPTab = uitab(app.TabGroup);
            app.PreprocessLFPTab.Title = 'Preprocess LFP';
            app.PreprocessLFPTab.ButtonDownFcn = createCallbackFcn(app, @PreprocessLFPTabButtonDown, true);
            app.ProbeLayoutPanel = uipanel(app.PreprocessLFPTab);
            app.ProbeLayoutPanel.Title = 'Probe Layout';
            app.LoadfilterdataPanel = uipanel(app.PreprocessLFPTab);
            app.LoadfilterdataPanel.Title = 'Load & filter data';
            app.LoadDataButton = uibutton(app.LoadfilterdataPanel, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.Lamp = uilamp(app.LoadfilterdataPanel);
            app.LowcutoffHzEditFieldLabel = uilabel(app.LoadfilterdataPanel);
            app.LowcutoffHzEditFieldLabel.Text = 'Low cut-off (Hz)';
            app.LowcutoffHzEditField = uieditfield(app.LoadfilterdataPanel, 'numeric');
            app.LowcutoffHzEditField.Value = 10;
            app.HighcutoffHzEditFieldLabel = uilabel(app.LoadfilterdataPanel);
            app.HighcutoffHzEditFieldLabel.Text = 'High cut-off (Hz)';
            app.HighcutoffHzEditField = uieditfield(app.LoadfilterdataPanel, 'numeric');
            app.HighcutoffHzEditField.Value = 200;
            app.SelectdataLabel = uilabel(app.LoadfilterdataPanel);
            app.SelectdataLabel.Text = 'Select data!';
            app.LFPViewerPanel = uipanel(app.PreprocessLFPTab);
            app.LFPViewerPanel.Title = 'LFP Viewer';
            app.FixdeadchannelsPanel = uipanel(app.PreprocessLFPTab);
            app.FixdeadchannelsPanel.Title = 'Fix dead channels';
            app.ofneighborsSpinnerLabel = uilabel(app.FixdeadchannelsPanel);
            app.ofneighborsSpinnerLabel.Text = '# of neighbors';
            app.ofneighborsSpinner = uispinner(app.FixdeadchannelsPanel);
            app.ofneighborsSpinner.Limits = [1 Inf];
            app.ofneighborsSpinner.Value = 2;
            app.SimilaritythresholdEditFieldLabel = uilabel(app.FixdeadchannelsPanel);
            app.SimilaritythresholdEditFieldLabel.Text = 'Similarity threshold';
            app.SimilaritythresholdEditField = uieditfield(app.FixdeadchannelsPanel, 'numeric');
            app.SimilaritythresholdEditField.Limits = [0 1];
            app.SimilaritythresholdEditField.Value = 0.85;
            app.DetectButton = uibutton(app.FixdeadchannelsPanel, 'push');
            app.DetectButton.ButtonPushedFcn = createCallbackFcn(app, @DetectButtonPushed, true);
            app.DetectButton.Text = 'Detect';
            app.LabelselectedButtonGroup = uibuttongroup(app.FixdeadchannelsPanel);
            app.LabelselectedButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @LabelselectedButtonGroupSelectionChanged, true);
            app.LabelselectedButtonGroup.Title = 'Label selected';
            app.DeadButton = uiradiobutton(app.LabelselectedButtonGroup);
            app.DeadButton.Text = 'Dead';
            app.DeadButton.Value = true;
            app.ConnectedButton = uiradiobutton(app.LabelselectedButtonGroup);
            app.ConnectedButton.Text = 'Connected';
            app.FixchannelsButton = uibutton(app.FixdeadchannelsPanel, 'push');
            app.FixchannelsButton.ButtonPushedFcn = createCallbackFcn(app, @FixchannelsButtonPushed, true);
            app.FixchannelsButton.Text = 'Fix channels';
            app.Lamp_2 = uilamp(app.FixdeadchannelsPanel);
            app.ShankSpinnerLabel = uilabel(app.FixdeadchannelsPanel);
            app.ShankSpinnerLabel.Text = 'Shank';
            app.ShankSpinner = uispinner(app.FixdeadchannelsPanel);
            app.ShankSpinner.ValueChangingFcn = createCallbackFcn(app, @ShankSpinnerValueChanging, true);
            app.ShankSpinner.ValueChangedFcn = createCallbackFcn(app, @ShankSpinnerValueChanged, true);
            app.ChannelSpinnerLabel = uilabel(app.FixdeadchannelsPanel);
            app.ChannelSpinnerLabel.Text = 'Channel';
            app.ChannelSpinner = uispinner(app.FixdeadchannelsPanel);
            app.ChannelSpinner.ValueChangedFcn = createCallbackFcn(app, @ShankSpinnerValueChanged, true);
            app.PlotsettingsPanel = uipanel(app.PreprocessLFPTab);
            app.PlotsettingsPanel.Title = 'Plot settings';
            app.LFPtoplotButtonGroup = uibuttongroup(app.PlotsettingsPanel);
            app.LFPtoplotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.LFPtoplotButtonGroup.Title = 'LFP to plot';
            app.rawLFPButton = uiradiobutton(app.LFPtoplotButtonGroup);
            app.rawLFPButton.Text = 'raw LFP';
            app.rawLFPButton.Value = true;
            app.fixedLFPButton = uiradiobutton(app.LFPtoplotButtonGroup);
            app.fixedLFPButton.Enable = 'off';
            app.fixedLFPButton.Text = 'fixed LFP';
            app.t0sEditFieldLabel = uilabel(app.PlotsettingsPanel);
            app.t0sEditFieldLabel.Text = 't0 (s)';
            app.t0sEditField = uieditfield(app.PlotsettingsPanel, 'numeric');
            app.t0sEditField.Value = 10;
            app.t1sEditFieldLabel = uilabel(app.PlotsettingsPanel);
            app.t1sEditFieldLabel.Text = 't1 (s)';
            app.t1sEditField = uieditfield(app.PlotsettingsPanel, 'numeric');
            app.t1sEditField.Value = 12;
            app.ShiftuVEditFieldLabel = uilabel(app.PlotsettingsPanel);
            app.ShiftuVEditFieldLabel.Text = 'Shift (uV)';
            app.ShiftuVEditField = uieditfield(app.PlotsettingsPanel, 'numeric');
            app.ShiftuVEditField.Value = 1500;
            app.PlotButton = uibutton(app.PlotsettingsPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.DetectDSTab = uitab(app.TabGroup);
            app.DetectDSTab.Title = 'Detect DS';
            app.DetectDSTab.ButtonDownFcn = createCallbackFcn(app, @DetectDSTabButtonDown, true);
            app.DSdetectionPanel = uipanel(app.DetectDSTab);
            app.DSdetectionPanel.Title = 'DS detection';
            app.ChannelselectionButtonGroup = uibuttongroup(app.DSdetectionPanel);
            app.ChannelselectionButtonGroup.Title = 'Channel selection';
            app.AutomaticestimationButton = uiradiobutton(app.ChannelselectionButtonGroup);
            app.AutomaticestimationButton.Text = 'Automatic estimation';
            app.ManualButton = uiradiobutton(app.ChannelselectionButtonGroup);
            app.ManualButton.Text = 'Manual';
            app.ManualButton.Value = true;
            app.UseshankSpinnerLabel = uilabel(app.DSdetectionPanel);
            app.UseshankSpinnerLabel.Text = 'Use shank';
            app.UseshankSpinner = uispinner(app.DSdetectionPanel);
            app.UsechannelEditFieldLabel = uilabel(app.DSdetectionPanel);
            app.UsechannelEditFieldLabel.Text = 'Use channel';
            app.UsechannelEditField = uieditfield(app.DSdetectionPanel, 'numeric');
            app.ThresholdzscoreEditFieldLabel = uilabel(app.DSdetectionPanel);
            app.ThresholdzscoreEditFieldLabel.Text = 'Threshold (z-score)';
            app.ThresholdzscoreEditField = uieditfield(app.DSdetectionPanel, 'numeric');
            app.ThresholdzscoreEditField.Value = 4;
            app.MinwidthmsEditFieldLabel = uilabel(app.DSdetectionPanel);
            app.MinwidthmsEditFieldLabel.Text = 'Min width (ms)';
            app.MinwidthmsEditField = uieditfield(app.DSdetectionPanel, 'numeric');
            app.MinwidthmsEditField.Value = 5;
            app.MaxwidthmsEditFieldLabel = uilabel(app.DSdetectionPanel);
            app.MaxwidthmsEditFieldLabel.Text = 'Max width (ms)';
            app.MaxwidthmsEditField = uieditfield(app.DSdetectionPanel, 'numeric');
            app.MaxwidthmsEditField.Value = 50;
            app.DetectDSButton = uibutton(app.DSdetectionPanel, 'push');
            app.DetectDSButton.ButtonPushedFcn = createCallbackFcn(app, @DetectDSButtonPushed, true);
            app.DetectDSButton.Text = ' Detect DS';
            app.Lamp_4 = uilamp(app.DSdetectionPanel);
            app.MaxampuVEditFieldLabel = uilabel(app.DSdetectionPanel);
            app.MaxampuVEditFieldLabel.Text = 'Max amp (uV)';
            app.MaxampuVEditField = uieditfield(app.DSdetectionPanel, 'numeric');
            app.MaxampuVEditField.Limits = [-1 Inf];
            app.MaxampuVEditField.Value = 5000;
            app.ReferencechEditFieldLabel = uilabel(app.DSdetectionPanel);
            app.ReferencechEditFieldLabel.Text = 'Reference ch';
            app.ReferencechEditField = uieditfield(app.DSdetectionPanel, 'numeric');
            app.TextArea = uitextarea(app.DetectDSTab);
            app.PlotsettingsPanel_2 = uipanel(app.DetectDSTab);
            app.PlotsettingsPanel_2.Title = 'Plot settings';
            app.PlotButton_2 = uibutton(app.PlotsettingsPanel_2, 'push');
            app.PlotButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton_2.Text = 'Plot';
            app.TimewindowmsEditFieldLabel = uilabel(app.PlotsettingsPanel_2);
            app.TimewindowmsEditFieldLabel.Text = 'Time window (ms)';
            app.TimewindowmsEditField = uieditfield(app.PlotsettingsPanel_2, 'numeric');
            app.TimewindowmsEditField.Value = 100;
            app.PlotmeanCheckBox = uicheckbox(app.PlotsettingsPanel_2);
            app.PlotmeanCheckBox.Text = 'Plot mean';
            app.ShiftuVEditField_2Label = uilabel(app.PlotsettingsPanel_2);
            app.ShiftuVEditField_2Label.Text = 'Shift (uV)';
            app.ShiftuVEditField_2 = uieditfield(app.PlotsettingsPanel_2, 'numeric');
            app.ShiftuVEditField_2.Value = 1500;
            app.LFPtoplotButtonGroup_2 = uibuttongroup(app.PlotsettingsPanel_2);
            app.LFPtoplotButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.LFPtoplotButtonGroup_2.Title = 'LFP to plot';
            app.rawLFPButton_2 = uiradiobutton(app.LFPtoplotButtonGroup_2);
            app.rawLFPButton_2.Text = 'raw LFP';
            app.fixedLFPButton_2 = uiradiobutton(app.LFPtoplotButtonGroup_2);
            app.fixedLFPButton_2.Text = 'fixed LFP';
            app.fixedLFPButton_2.Value = true;
            app.DSSpinnerLabel = uilabel(app.PlotsettingsPanel_2);
            app.DSSpinnerLabel.Text = 'DS #';
            app.DSSpinner = uispinner(app.PlotsettingsPanel_2);
            app.DSSpinner.ValueChangedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.Switch_3 = uiswitch(app.PlotsettingsPanel_2, 'slider');
            app.Switch_3.Items = {'LFP', 'Params'};
            app.Switch_3.ValueChangedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.Switch_3.Value = 'LFP';
            app.ProbeLayoutPanel_2 = uipanel(app.DetectDSTab);
            app.ProbeLayoutPanel_2.Title = 'Probe Layout';
            app.PutativeDSViewerPanel = uipanel(app.DetectDSTab);
            app.PutativeDSViewerPanel.Title = 'Putative DS Viewer';
            app.EstimateMLTab = uitab(app.TabGroup);
            app.EstimateMLTab.Title = 'Estimate ML';
            app.EstimateMLTab.ButtonDownFcn = createCallbackFcn(app, @EstimateMLTabButtonDown, true);
            app.ProbeLayoutPanel_3 = uipanel(app.EstimateMLTab);
            app.ProbeLayoutPanel_3.Title = 'Probe Layout';
            app.ClusterViewerPanel = uipanel(app.EstimateMLTab);
            app.ClusterViewerPanel.Title = 'Cluster Viewer';
            app.CurrentsourcedensityPanel = uipanel(app.EstimateMLTab);
            app.CurrentsourcedensityPanel.Title = 'Current-source density';
            app.TimewindowmsEditField_2Label = uilabel(app.CurrentsourcedensityPanel);
            app.TimewindowmsEditField_2Label.Text = 'Time window (ms)';
            app.TimewindowmsEditField_2 = uieditfield(app.CurrentsourcedensityPanel, 'numeric');
            app.TimewindowmsEditField_2.Limits = [1 500];
            app.TimewindowmsEditField_2.Value = 50;
            app.KernellengthEditFieldLabel = uilabel(app.CurrentsourcedensityPanel);
            app.KernellengthEditFieldLabel.Text = 'Kernel length';
            app.KernellengthEditField = uieditfield(app.CurrentsourcedensityPanel, 'numeric');
            app.KernellengthEditField.Value = 1000;
            app.KernelsigmaEditFieldLabel = uilabel(app.CurrentsourcedensityPanel);
            app.KernelsigmaEditFieldLabel.Text = 'Kernel sigma';
            app.KernelsigmaEditField = uieditfield(app.CurrentsourcedensityPanel, 'numeric');
            app.KernelsigmaEditField.Value = 1.5;
            app.ComputeButton = uibutton(app.CurrentsourcedensityPanel, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton.Text = 'Compute';
            app.Lamp_5 = uilamp(app.CurrentsourcedensityPanel);
            app.PlotsettingsPanel_4 = uipanel(app.EstimateMLTab);
            app.PlotsettingsPanel_4.Title = 'Plot settings';
            app.PlotButton_3 = uibutton(app.PlotsettingsPanel_4, 'push');
            app.PlotButton_3.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton_3.Text = 'Plot';
            app.climEditFieldLabel = uilabel(app.PlotsettingsPanel_4);
            app.climEditFieldLabel.Text = 'clim';
            app.climEditField = uieditfield(app.PlotsettingsPanel_4, 'numeric');
            app.climEditField.Value = 0.05;
            app.HeatmapDropDownLabel = uilabel(app.PlotsettingsPanel_4);
            app.HeatmapDropDownLabel.Text = 'Heatmap';
            app.HeatmapDropDown = uidropdown(app.PlotsettingsPanel_4);
            app.HeatmapDropDown.Items = {'parula', 'turbo', 'hot', 'spring', 'summer', 'autumn', 'winter', 'gray', 'bone', 'copper', 'pink', 'jet', 'sky', 'abyss'};
            app.HeatmapDropDown.Value = 'parula';
            app.ClusteringPanel = uipanel(app.EstimateMLTab);
            app.ClusteringPanel.Title = 'Clustering';
            app.OMLEditFieldLabel = uilabel(app.ClusteringPanel);
            app.OMLEditFieldLabel.Text = 'OML';
            app.OMLEditField = uieditfield(app.ClusteringPanel, 'numeric');
            app.OMLEditField.Editable = 'off';
            app.MMLEditFieldLabel = uilabel(app.ClusteringPanel);
            app.MMLEditFieldLabel.Text = 'MML';
            app.MMLEditField = uieditfield(app.ClusteringPanel, 'numeric');
            app.MMLEditField.Editable = 'off';
            app.HILEditField_2Label = uilabel(app.ClusteringPanel);
            app.HILEditField_2Label.Text = 'HIL';
            app.HILEditField_2 = uieditfield(app.ClusteringPanel, 'numeric');
            app.HILEditField_2.Editable = 'off';
            app.ileEditFieldLabel = uilabel(app.ClusteringPanel);
            app.ileEditFieldLabel.Text = '%ile';
            app.ileEditField = uieditfield(app.ClusteringPanel, 'numeric');
            app.ileEditField.Value = 75;
            app.ClusterButton = uibutton(app.ClusteringPanel, 'push');
            app.ClusterButton.ButtonPushedFcn = createCallbackFcn(app, @ClusterButtonPushed2, true);
            app.ClusterButton.Text = 'Cluster';
            app.MethodButtonGroup = uibuttongroup(app.ClusteringPanel);
            app.MethodButtonGroup.Title = 'Method';
            app.PCXButton = uiradiobutton(app.MethodButtonGroup);
            app.PCXButton.Text = 'PCX';
            app.PCXButton.Value = true;
            app.ManualButton_2 = uiradiobutton(app.MethodButtonGroup);
            app.ManualButton_2.Text = 'Manual';
            app.PCXSpinnerLabel = uilabel(app.ClusteringPanel);
            app.PCXSpinnerLabel.Text = 'PCX';
            app.PCXSpinner = uispinner(app.ClusteringPanel);
            app.PCXSpinner.ValueChangedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PCYSpinnerLabel = uilabel(app.ClusteringPanel);
            app.PCYSpinnerLabel.Text = 'PCY';
            app.PCYSpinner = uispinner(app.ClusteringPanel);
            app.PCYSpinner.ValueChangedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PCAchannelsLabel = uilabel(app.ClusteringPanel);
            app.PCAchannelsLabel.Text = 'PCA channels';
            app.FromEditFieldLabel = uilabel(app.ClusteringPanel);
            app.FromEditFieldLabel.Text = 'From';
            app.FromEditField = uieditfield(app.ClusteringPanel, 'numeric');
            app.ToEditFieldLabel = uilabel(app.ClusteringPanel);
            app.ToEditFieldLabel.Text = 'To';
            app.ToEditField = uieditfield(app.ClusteringPanel, 'numeric');
            app.RunPCAButton = uibutton(app.ClusteringPanel, 'push');
            app.RunPCAButton.ButtonPushedFcn = createCallbackFcn(app, @RunPCAButtonPushed, true);
            app.RunPCAButton.Text = 'Run PCA';
            app.AddlayersPanel = uipanel(app.EstimateMLTab);
            app.AddlayersPanel.Title = 'Add layers';
            app.lowerOMLEditFieldLabel = uilabel(app.AddlayersPanel);
            app.lowerOMLEditFieldLabel.Text = 'lower OML';
            app.lowerOMLEditField = uieditfield(app.AddlayersPanel, 'numeric');
            app.lowerMMLEditFieldLabel = uilabel(app.AddlayersPanel);
            app.lowerMMLEditFieldLabel.Text = 'lower MML';
            app.lowerMMLEditField = uieditfield(app.AddlayersPanel, 'numeric');
            app.AddlayersButton = uibutton(app.AddlayersPanel, 'push');
            app.AddlayersButton.ButtonPushedFcn = createCallbackFcn(app, @lowerOMLEditFieldValueChanged, true);
            app.AddlayersButton.Text = 'Add layers';
            app.UseshankSpinner_2Label = uilabel(app.EstimateMLTab);
            app.UseshankSpinner_2Label.Text = 'Use shank';
            app.UseshankSpinner_2 = uispinner(app.EstimateMLTab);
            app.ClassifyDSTab_2 = uitab(app.TabGroup);
            app.ClassifyDSTab_2.Title = 'Classify DS';
            app.ClassifyDSTab_2.ButtonDownFcn = createCallbackFcn(app, @ClassifyDSTab_2ButtonDown, true);
            app.ClassificationPanel = uipanel(app.ClassifyDSTab_2);
            app.ClassificationPanel.Title = 'Classification';
            app.ClassifyButton = uibutton(app.ClassificationPanel, 'push');
            app.ClassifyButton.ButtonPushedFcn = createCallbackFcn(app, @ClassifyButtonPushed, true);
            app.ClassifyButton.Text = 'Classify';
            app.UseshankSpinner_3Label = uilabel(app.ClassificationPanel);
            app.UseshankSpinner_3Label.Text = 'Use shank';
            app.UseshankSpinner_3 = uispinner(app.ClassificationPanel);
            app.SinkcutoffEditFieldLabel = uilabel(app.ClassificationPanel);
            app.SinkcutoffEditFieldLabel.Text = 'Sink cut-off';
            app.SinkcutoffEditField = uieditfield(app.ClassificationPanel, 'numeric');
            app.SinkcutoffEditField.Limits = [0 1];
            app.SinkcutoffEditField.Value = 0.5;
            app.DSViewerPanel = uipanel(app.ClassifyDSTab_2);
            app.DSViewerPanel.Title = 'DS Viewer';
            app.PlotsettingsPanel_5 = uipanel(app.ClassifyDSTab_2);
            app.PlotsettingsPanel_5.Title = 'Plot settings';
            app.Switch_2 = uiswitch(app.PlotsettingsPanel_5, 'slider');
            app.Switch_2.Items = {'CSD', 'LFP'};
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.Switch_2.Value = 'CSD';
            app.PlotButton_4 = uibutton(app.PlotsettingsPanel_5, 'push');
            app.PlotButton_4.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton_4.Text = 'Plot';
            app.PlotmeanCheckBox_2 = uicheckbox(app.PlotsettingsPanel_5);
            app.PlotmeanCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @DStoplotButtonGroup_2SelectionChanged, true);
            app.PlotmeanCheckBox_2.Text = 'Plot mean';
            app.PlotmeanCheckBox_2.Value = true;
            app.DSSpinner_2Label = uilabel(app.PlotsettingsPanel_5);
            app.DSSpinner_2Label.Text = 'DS #';
            app.DSSpinner_2 = uispinner(app.PlotsettingsPanel_5);
            app.DSSpinner_2.ValueChangedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.MultishankCheckBox = uicheckbox(app.PlotsettingsPanel_5);
            app.MultishankCheckBox.Text = 'Multi-shank';
            app.DStoplotButtonGroup = uibuttongroup(app.PlotsettingsPanel_5);
            app.DStoplotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DStoplotButtonGroup_2SelectionChanged, true);
            app.DStoplotButtonGroup.Title = 'DS to plot';
            app.DS1Button = uiradiobutton(app.DStoplotButtonGroup);
            app.DS1Button.Text = 'DS1';
            app.DS1Button.Value = true;
            app.DS2Button = uiradiobutton(app.DStoplotButtonGroup);
            app.DS2Button.Text = 'DS2';
            app.DS3Button = uiradiobutton(app.DStoplotButtonGroup);
            app.DS3Button.Text = 'DS3';
            app.climEditField_2Label = uilabel(app.PlotsettingsPanel_5);
            app.climEditField_2Label.Text = 'clim';
            app.climEditField_2 = uieditfield(app.PlotsettingsPanel_5, 'numeric');
            app.climEditField_2.Value = 0.05;
            app.HeatmapDropDown_2Label = uilabel(app.PlotsettingsPanel_5);
            app.HeatmapDropDown_2Label.Text = 'Heatmap';
            app.HeatmapDropDown_2 = uidropdown(app.PlotsettingsPanel_5);
            app.HeatmapDropDown_2.Items = {'parula', 'turbo', 'hot', 'spring', 'summer', 'autumn', 'winter', 'gray', 'bone', 'copper', 'pink', 'jet', 'sky', 'abyss'};
            app.HeatmapDropDown_2.Value = 'parula';
            app.MultichannelCheckBox = uicheckbox(app.PlotsettingsPanel_5);
            app.MultichannelCheckBox.Text = 'Multi-channel';
            app.TimewindowmsEditField_3Label = uilabel(app.PlotsettingsPanel_5);
            app.TimewindowmsEditField_3Label.Text = 'Time window (ms)';
            app.TimewindowmsEditField_3 = uieditfield(app.PlotsettingsPanel_5, 'numeric');
            app.TimewindowmsEditField_3.Value = 100;
            app.ShowcoincidenceCheckBox = uicheckbox(app.PlotsettingsPanel_5);
            app.ShowcoincidenceCheckBox.Text = 'Show coincidence';
            app.ShiftuVEditField_3Label = uilabel(app.PlotsettingsPanel_5);
            app.ShiftuVEditField_3Label.Text = 'Shift (uV)';
            app.ShiftuVEditField_3 = uieditfield(app.PlotsettingsPanel_5, 'numeric');
            app.ShiftuVEditField_3.Value = 1500;
            app.ContributionViewerPanel = uipanel(app.ClassifyDSTab_2);
            app.ContributionViewerPanel.Title = 'Contribution Viewer';
            app.SaveresultsPanel = uipanel(app.ClassifyDSTab_2);
            app.SaveresultsPanel.Title = 'Save results';
            app.SaveButton = uibutton(app.SaveresultsPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.FilenameEditFieldLabel = uilabel(app.SaveresultsPanel);
            app.FilenameEditFieldLabel.Text = 'File name';
            app.FilenameEditField = uieditfield(app.SaveresultsPanel, 'text');
            app.FilenameEditField.Value = 'DSclust_out';
            app.ReadjustMLPanel = uipanel(app.ClassifyDSTab_2);
            app.ReadjustMLPanel.Title = 'Readjust ML';
            app.OMLEditField_2Label = uilabel(app.ReadjustMLPanel);
            app.OMLEditField_2Label.Text = 'OML';
            app.OMLEditField_2 = uieditfield(app.ReadjustMLPanel, 'numeric');
            app.MMLEditField_2Label = uilabel(app.ReadjustMLPanel);
            app.MMLEditField_2Label.Text = 'MML';
            app.MMLEditField_2 = uieditfield(app.ReadjustMLPanel, 'numeric');
            app.HILEditFieldLabel = uilabel(app.ReadjustMLPanel);
            app.HILEditFieldLabel.Text = 'HIL';
            app.HILEditField = uieditfield(app.ReadjustMLPanel, 'numeric');
            app.HILEditField.Editable = 'off';
            app.EditMLButton = uibutton(app.ReadjustMLPanel, 'push');
            app.EditMLButton.ButtonPushedFcn = createCallbackFcn(app, @AddmanuallyButtonPushed, true);
            app.EditMLButton.Text = 'Edit ML';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DentateSpikeInterface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end